classdef ToolboxExtenderApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ToolboxExtenderAppUIFigure  matlab.ui.Figure
        TE                          matlab.ui.control.Button
        ToolboxExtender             matlab.ui.control.CheckBox
        ToolboxDev                  matlab.ui.control.CheckBox
        ToolboxUpdater              matlab.ui.control.CheckBox
        ToolboxStorage              matlab.ui.control.CheckBox
        Init                        matlab.ui.control.Button
        HelpE                       matlab.ui.control.Button
        HelpD                       matlab.ui.control.Button
        HelpU                       matlab.ui.control.Button
        HelpS                       matlab.ui.control.Button
        Start                       matlab.ui.control.Button
        vc                          matlab.ui.control.Label
        vr                          matlab.ui.control.Label
        Update                      matlab.ui.control.Button
    end

    
    properties (Access = private)
        TU % ToolboxUpdater
    end
    
    methods (Access = private)
        
        function ShowUpdate(app, isupd)
            % Show update button
            if isupd
                app.vr.Text = "Latest: " + app.TU.gvr();
                app.vr.Visible = 'on';
                app.Update.Visible = 'on';
            end
        end
        
        function wb = waitbar(app, title)
            % Wait bar
            wb = uiprogressdlg(app.ToolboxExtenderAppUIFigure, 'Title', title, 'Indeterminate', 'on');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TU = ToolboxUpdater();
            app.TU.isupdate(@app.ShowUpdate, 3);
            app.vc.Text = "Version: " + app.TU.ext.vc;
        end

        % Button pushed function: TE
        function TEButtonPushed(app, event)
            app.TU.ext.web();
        end

        % Button pushed function: Start
        function StartButtonPushed(app, event)
            ToolboxExtender.help
        end

        % Button pushed function: HelpE
        function HelpEButtonPushed(app, event)
            ToolboxExtender.help('ToolboxExtender')
        end

        % Button pushed function: HelpD
        function HelpDButtonPushed(app, event)
            ToolboxExtender.help('ToolboxDev')
        end

        % Button pushed function: HelpU
        function HelpUButtonPushed(app, event)
            ToolboxExtender.help('ToolboxUpdater')
        end

        % Button pushed function: HelpS
        function HelpSButtonPushed(app, event)
            ToolboxExtender.help('ToolboxStorage')
        end

        % Button pushed function: Update
        function UpdateButtonPushed(app, event)
            wb = app.waitbar('Update in prpogress');
            app.TU.update(2, @() delete(app), @ToolboxExtenderApp);
        end

        % Button pushed function: Init
        function InitButtonPushed(app, event)
            T = [app.ToolboxExtender app.ToolboxDev app.ToolboxUpdater app.ToolboxStorage];
            vals = cell2mat(get(T, 'Value'));
            if all(vals)
                ToolboxExtender.add('all')
            else
                if vals(2)
                    ToolboxExtender.add('dev')
                end
                if vals(3)
                    ToolboxExtender.add('updater')
                end
                if vals(4)
                    ToolboxExtender.add('storage')
                end
                if vals(1) && ~any(vals(2:end))
                    ToolboxExtender.add('extender')
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ToolboxExtenderAppUIFigure = uifigure('Visible', 'off');
            app.ToolboxExtenderAppUIFigure.Name = 'Toolbox Extender App';
            app.TE = uibutton(app.ToolboxExtenderAppUIFigure, 'push');
            app.TE.ButtonPushedFcn = createCallbackFcn(app, @TEButtonPushed, true);
            app.TE.Icon = 'ToolboxExtender-icon.png';
            app.TE.Tooltip = {'Project on GitHub'};
            app.TE.Text = '';
            app.ToolboxExtender = uicheckbox(app.ToolboxExtenderAppUIFigure);
            app.ToolboxExtender.Enable = 'off';
            app.ToolboxExtender.Text = 'ToolboxExtender';
            app.ToolboxExtender.Value = true;
            app.ToolboxDev = uicheckbox(app.ToolboxExtenderAppUIFigure);
            app.ToolboxDev.Text = 'ToolboxDev';
            app.ToolboxDev.Value = true;
            app.ToolboxUpdater = uicheckbox(app.ToolboxExtenderAppUIFigure);
            app.ToolboxUpdater.Text = 'ToolboxUpdater';
            app.ToolboxUpdater.Value = true;
            app.ToolboxStorage = uicheckbox(app.ToolboxExtenderAppUIFigure);
            app.ToolboxStorage.Text = 'ToolboxStorage';
            app.ToolboxStorage.Value = true;
            app.Init = uibutton(app.ToolboxExtenderAppUIFigure, 'push');
            app.Init.ButtonPushedFcn = createCallbackFcn(app, @InitButtonPushed, true);
            app.Init.Text = 'Add to current folder';
            app.HelpE = uibutton(app.ToolboxExtenderAppUIFigure, 'push');
            app.HelpE.ButtonPushedFcn = createCallbackFcn(app, @HelpEButtonPushed, true);
            app.HelpE.Icon = 'icon-mat-help-outline.png';
            app.HelpE.Tooltip = {'What''s this?'};
            app.HelpE.Text = '';
            app.HelpD = uibutton(app.ToolboxExtenderAppUIFigure, 'push');
            app.HelpD.ButtonPushedFcn = createCallbackFcn(app, @HelpDButtonPushed, true);
            app.HelpD.Icon = 'icon-mat-help-outline.png';
            app.HelpD.Tooltip = {'What''s this?'};
            app.HelpD.Text = '';
            app.HelpU = uibutton(app.ToolboxExtenderAppUIFigure, 'push');
            app.HelpU.ButtonPushedFcn = createCallbackFcn(app, @HelpUButtonPushed, true);
            app.HelpU.Icon = 'icon-mat-help-outline.png';
            app.HelpU.Tooltip = {'What''s this?'};
            app.HelpU.Text = '';
            app.HelpS = uibutton(app.ToolboxExtenderAppUIFigure, 'push');
            app.HelpS.ButtonPushedFcn = createCallbackFcn(app, @HelpSButtonPushed, true);
            app.HelpS.Icon = 'icon-mat-help-outline.png';
            app.HelpS.Tooltip = {'What''s this?'};
            app.HelpS.Text = '';
            app.Start = uibutton(app.ToolboxExtenderAppUIFigure, 'push');
            app.Start.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.Start.Icon = 'icon-mat-help-outline.png';
            app.Start.Text = 'Getting Started';
            app.vc = uilabel(app.ToolboxExtenderAppUIFigure);
            app.vc.Text = 'v';
            app.vr = uilabel(app.ToolboxExtenderAppUIFigure);
            app.vr.Visible = 'off';
            app.vr.Text = '';
            app.Update = uibutton(app.ToolboxExtenderAppUIFigure, 'push');
            app.Update.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.Update.Icon = 'icon-my-update-goldenrod.png';
            app.Update.Visible = 'off';
            app.Update.Tooltip = {'Update Toolbox Extender'};
            app.Update.Text = '';
            % Show the figure after all components are created
            app.ToolboxExtenderAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ToolboxExtenderApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ToolboxExtenderAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ToolboxExtenderAppUIFigure)
        end
    end
end